classdef splApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        buttonLoadWav                matlab.ui.control.Button
        CalibrationEditFieldLabel    matlab.ui.control.Label
        editCalibration              matlab.ui.control.EditField
        tableResultOctave            matlab.ui.control.Table
        sliderMin                    matlab.ui.control.Slider
        sliderMax                    matlab.ui.control.Slider
        buttonCalc                   matlab.ui.control.Button
        tableResult                  matlab.ui.control.Table
        editMin                      matlab.ui.control.NumericEditField
        editMax                      matlab.ui.control.NumericEditField
        FilterorderEditField_2Label  matlab.ui.control.Label
        editFilterOrder              matlab.ui.control.NumericEditField
        BandwidthDropDownLabel       matlab.ui.control.Label
        BandwidthDropDown            matlab.ui.control.DropDown
        fileName                     matlab.ui.control.Label
        ChannelDropDownLabel         matlab.ui.control.Label
        editChannel                  matlab.ui.control.DropDown
        Tree                         matlab.ui.container.Tree
        TableNode                    matlab.ui.container.TreeNode
        SPLTableNode                 matlab.ui.container.TreeNode
        OctaveTableNode              matlab.ui.container.TreeNode
        PlotNode                     matlab.ui.container.TreeNode
        SPLNode                      matlab.ui.container.TreeNode
        OctaveNode                   matlab.ui.container.TreeNode
        barNode                      matlab.ui.container.TreeNode
        lineNode                     matlab.ui.container.TreeNode
        tableResultTime              matlab.ui.control.Table
        TimeDropDownLabel            matlab.ui.control.Label
        dropTime                     matlab.ui.control.DropDown
        timePlot                     matlab.ui.control.UIAxes
        splPlot                      matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function plotChannel(app)
            % Get input values
            channel = str2double(app.editChannel.Value);
            calib = eval(app.editCalibration.Value);
            % Get guidata
            data = guidata(app.timePlot);
            % Plot
            y = calib.*data.data(:,channel);
            t = data.time;
            plot(app.timePlot,t,y)
            xlim(app.timePlot,[min(t), max(t)])
            
            % Set edit fields equal to slider
            app.editMin.Value = app.sliderMin.Value*t(end)/100;
            app.editMax.Value = app.sliderMax.Value*t(end)/100;
            % Reset patches
            app.patchPlot(t);   
        end
        
        function patchPlot(app, t)
            % Find and delete existing pathces            
            h = findobj(app.timePlot, 'Type', 'Patch');
            delete(h)
            
            yl = get(app.timePlot,'ylim');
            pathcolor = [0.5 0.5 0.5];
            patchalpha = 0.3;
            % Patch from left            
            tValue = app.editMin.Value;
            if tValue>=t(1)             
                x1 = t(1);
                x2 = tValue;
                y1 = yl(1);
                y2 = yl(2);
                patch(app.timePlot, 'XData', [x1 x2 x2 x1], 'YData', [y1 y1 y2 y2], ...
                    'FaceColor', pathcolor, 'FaceAlpha', patchalpha);
            end
            
            % Patch from right
            tValue = app.editMax.Value;
            if tValue<=t(end)
                x2 = t(end);
                x1 = tValue;
                y1 = yl(1);
                y2 = yl(2);
                patch(app.timePlot, 'XData', [x1 x2 x2 x1], 'YData', [y1 y1 y2 y2], ...
                    'FaceColor', pathcolor, 'FaceAlpha', patchalpha);
            end
        end
        
        function plotOctave(app, type)
            % [Fc,Loct,LAoct]
            values = app.tableResultOctave.Data;
            Fc = values(:,1);
            Loct = values(:,2:3);
            bandwidth = app.BandwidthDropDown.Value;
  
            switch type
                case 'line'
                    plot(app.splPlot,Loct)
                    
                case 'bar'
                    bar(app.splPlot,Loct)
            end
            legend(app.splPlot,'dB','dB(A)')
            title(app.splPlot, bandwidth);
            xlabel(app.splPlot, 'Frequency (Hz)')
            
            % Fix with xlabels
            if strcmpi(bandwidth,'1/3 octave')
                xt = 2:3:length(Fc);
                xtl = cell(1,length(xt));
                for k = 1:length(xt)
                    n = xt(k);
                    if Fc(n)<1000
                        xtl{k} = num2str(round(Fc(n)));
                    else
                        xtl{k} = sprintf('%.0fk',Fc(n)*1e-3);
                    end
                end
            else
                xt = 1:1:length(Fc);
                xtl = cell(1,length(xt));
                for k = 1:length(xt)
                    n = xt(k);
                    if Fc(n)<1000
                        xtl{k} = num2str(round(Fc(n)));
                    else
                        xtl{k} = sprintf('%.0fk',Fc(n)*1e-3);
                    end
                end
            end
            set(app.splPlot,'xtick',xt,'xticklabel',xtl)
        end
        
        function plotSpl(app)
            values = app.tableResultTime.Data;
            t = values(:,1);
            Lp = values(:,2);
            LA = values(:,3);
            LC = values(:,4);
            plot(app.splPlot, t, Lp, t, LA, t, LC)
            lgd = legend(app.splPlot, 'dB', 'dB(A)', 'dB(C)');
            xlabel(app.splPlot, 'Time (s)')
            title(app.splPlot, '');
            set(app.splPlot, 'XTickMode', 'auto', 'XTickLabelMode', 'auto')
            
            % Set the legend's 'PickableParts' property to 'visible'
            set(lgd, 'PickableParts', 'visible', 'units', 'pixels');
            
            % Assign a ButtonDownFcn to the legend
            set(lgd, 'ButtonDownFcn', {@toggleVisibility, app.splPlot});
            
            % Callback function to toggle line visibility
            function toggleVisibility(src, ~, ax)
                
                % Get the current point clicked in the legend
                fig = get(ax,'Parent');
                %cp = get(ax,'CurrentPoint')
                cp = get(fig, 'CurrentPoint');
                
                % Determine the corresponding legend entry
                num_entries = length(src.String);
                position = get(src, 'Position');
                y_lower = position(2);
                y_extent = position(4);
                dy = y_extent / num_entries;
                entry_index = num_entries - floor((cp(1,2)-y_lower) / dy);
                
                % Toggle the visibility of the corresponding line
                h = findobj(get(src, 'Parent'), 'DisplayName', src.String{entry_index});
                if ~isempty(h)
                    if strcmp(h.Visible, 'on')
                        h.Visible = 'off';
                    else
                        h.Visible = 'on';
                    end
                end
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: buttonLoadWav
        function loadData(app, event)
            % Load data
            [file,path] = uigetfile('*.wav');
            if file==0
                return
            end
            filename = fullfile(path,file);
            [data,fs] = audioread(filename);
            t = (0:length(data)-1)./fs;
            app.fileName.Text = file;
            
            app.editChannel.Items = cellstr(num2str((1:size(data,2))'));
            app.editChannel.Value = num2str(size(data,2));
            app.editMin.Value = round(t(1),1);
            app.editMin.Limits = [floor(t(1)),floor(t(end))];
            app.editMax.Value = round(t(end),1);
            app.editMax.Limits = [ceil(t(1)),ceil(t(end))];
            app.sliderMin.Value = 0;
            app.sliderMax.Value = 100;
            
            guidata(app.timePlot,struct('data',data,'fs',fs,'time',t))
            app.plotChannel()
            
            app.buttonCalc.Enable = 'on';
        end

        % Value changed function: sliderMin
        function updateSliderMin(app, event)
            value = app.sliderMin.Value;
            if value >= app.sliderMax.Value
                app.sliderMin.Value = app.sliderMax.Value - 1;
            end
            t = guidata(app.sliderMin).time;
            tValue = max(t(end)/100*app.sliderMin.Value,t(1));
            app.editMin.Value = round(tValue,1);
            app.patchPlot(t);
            
        end

        % Value changed function: sliderMax
        function updateSliderMax(app, event)
            value = app.sliderMax.Value;
            if value <= app.sliderMin.Value
                app.sliderMax.Value = app.sliderMin.Value + 1;
            end
            t = guidata(app.sliderMax).time;
            tValue = min(t(end)/100*app.sliderMax.Value,t(end));
            app.editMax.Value = round(tValue,1);
            app.patchPlot(t);
        end

        % Button pushed function: buttonCalc
        function calculateSpl(app, event)
            
            splSetup = struct(...
                'channel', str2double(app.editChannel.Value),...
                'calib', eval(app.editCalibration.Value),...
                'timefilter', app.dropTime.Value,...
                'timelimits', [app.editMin.Value, app.editMax.Value],...
                'outputformat', 'struct');
            
            data = guidata(app.buttonCalc);
            result = spl(data, splSetup);
            
            % Octave analysis
            splSetup.bandwidth = app.BandwidthDropDown.Value;
            splSetup.filterorder = app.editFilterOrder.Value;
            octresult = spl(data, splSetup);
            
            % Display results
            app.tableResult.Data = [...
                [result.Leq;    octresult.Leq],...
                [result.LAeq;   octresult.LAeq],...
                [result.LCeq;   octresult.LCeq], ...
                [result.LAmax;  octresult.LAmax],...
                [result.LCpeak; octresult.LCpeak]];
            
            app.tableResultTime.Data = [result.t, result.L, result.LA, result.LC];
            
            app.tableResultOctave.Data = [octresult.Fc, octresult.Loct, octresult.LAoct];
           
            app.updatePlot()
            
        end

        % Value changed function: editMin
        function updateEditMin(app, event)
            value = app.editMin.Value;
            if value >= app.editMax.Value
                app.editMin.Value = app.editMax.Value - 1;
            end
            t = guidata(app.editMax).time;
            app.sliderMin.Value = app.editMin.Value/(t(end)-t(1))*100;
            app.patchPlot(t);
        end

        % Value changed function: editMax
        function updateEditMax(app, event)
            value = app.editMax.Value;
            if value <= app.editMin.Value
                app.editMax.Value = app.editMin.Value + 1;
            end
            t = guidata(app.editMax).time;
            app.sliderMax.Value = app.editMax.Value/(t(end)-t(1))*100;
            app.patchPlot(t);
        end

        % Value changed function: editChannel
        function updateEditChannel(app, event)
            app.sliderMin.Value = 0;
            app.sliderMax.Value = 100;
            app.plotChannel();
        end

        % Callback function
        function updateDropBandwidth(app, event)
            value = app.BandwidthDropDown.Value;
            app.splPlot.Title.String = value;
        end

        % Value changed function: editCalibration
        function updateEditCalibration(app, event)
            value = eval(app.editCalibration.Value);
            if isfinite(value)
                app.plotChannel();
            else
                app.editCalibration.Value = 'nan';
            end
        end

        % Selection changed function: Tree
        function updatePlot(app, event)
            selectedNode = app.Tree.SelectedNodes;
            if isempty(selectedNode)
                app.plotSpl();
                app.tableResultTime.Visible = 'off';
                app.tableResultOctave.Visible = 'on';
                return
            end
            switch selectedNode.Tag
                case 'plot'
                    switch selectedNode.Text
                        case 'bar'
                            app.plotOctave('bar')
                        case 'line'
                            app.plotOctave('line')
                        otherwise
                            app.plotSpl()
                    end
                case 'table'
                    switch selectedNode.Text
                        case 'SPL'
                            app.tableResultOctave.Visible = 'off';
                            app.tableResultTime.Visible = 'on';
                        otherwise
                            app.tableResultTime.Visible = 'off';
                            app.tableResultOctave.Visible = 'on';
                            
                    end
            end     
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.buttonLoadWav = uibutton(app.UIFigure, 'push');
            app.buttonLoadWav.ButtonPushedFcn = createCallbackFcn(app, @loadData, true);
            app.buttonLoadWav.Text = 'Load WAV file';
            app.CalibrationEditFieldLabel = uilabel(app.UIFigure);
            app.CalibrationEditFieldLabel.Text = 'Calibration';
            app.editCalibration = uieditfield(app.UIFigure, 'text');
            app.editCalibration.ValueChangedFcn = createCallbackFcn(app, @updateEditCalibration, true);
            app.editCalibration.Value = '20.97*23.6';
            app.tableResultOctave = uitable(app.UIFigure);
            app.tableResultOctave.ColumnName = {'Freq'; 'dB'; 'dB(A)'};
            app.tableResultOctave.RowName = {};
            app.sliderMin = uislider(app.UIFigure);
            app.sliderMin.MajorTicks = [];
            app.sliderMin.MajorTickLabels = {''};
            app.sliderMin.ValueChangedFcn = createCallbackFcn(app, @updateSliderMin, true);
            app.sliderMin.MinorTicks = [];
            app.sliderMax = uislider(app.UIFigure);
            app.sliderMax.MajorTicks = [];
            app.sliderMax.MajorTickLabels = {''};
            app.sliderMax.ValueChangedFcn = createCallbackFcn(app, @updateSliderMax, true);
            app.sliderMax.MinorTicks = [];
            app.sliderMax.Value = 100;
            app.buttonCalc = uibutton(app.UIFigure, 'push');
            app.buttonCalc.ButtonPushedFcn = createCallbackFcn(app, @calculateSpl, true);
            app.buttonCalc.Enable = 'off';
            app.buttonCalc.Text = 'Calculate';
            app.tableResult = uitable(app.UIFigure);
            app.tableResult.ColumnName = {'Leq'; 'LAeq'; 'LCeq'; 'LAmax'; 'LCpeak'};
            app.tableResult.RowName = {'full'; 'sum'};
            app.editMin = uieditfield(app.UIFigure, 'numeric');
            app.editMin.ValueDisplayFormat = '%.1f';
            app.editMin.ValueChangedFcn = createCallbackFcn(app, @updateEditMin, true);
            app.editMax = uieditfield(app.UIFigure, 'numeric');
            app.editMax.ValueDisplayFormat = '%.1f';
            app.editMax.ValueChangedFcn = createCallbackFcn(app, @updateEditMax, true);
            app.editMax.Value = 100;
            app.FilterorderEditField_2Label = uilabel(app.UIFigure);
            app.FilterorderEditField_2Label.Text = 'Filter order';
            app.editFilterOrder = uieditfield(app.UIFigure, 'numeric');
            app.editFilterOrder.Limits = [0 10];
            app.editFilterOrder.ValueDisplayFormat = '%.0f';
            app.editFilterOrder.Value = 6;
            app.BandwidthDropDownLabel = uilabel(app.UIFigure);
            app.BandwidthDropDownLabel.Text = 'Bandwidth';
            app.BandwidthDropDown = uidropdown(app.UIFigure);
            app.BandwidthDropDown.Items = {'1/3 octave', '1 octave'};
            app.BandwidthDropDown.Value = '1/3 octave';
            app.fileName = uilabel(app.UIFigure);
            app.fileName.Text = 'No file loaded';
            app.ChannelDropDownLabel = uilabel(app.UIFigure);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.editChannel = uidropdown(app.UIFigure);
            app.editChannel.Items = {'1', '2', '3', '4'};
            app.editChannel.ValueChangedFcn = createCallbackFcn(app, @updateEditChannel, true);
            app.editChannel.Value = '1';
            app.Tree = uitree(app.UIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @updatePlot, true);
            app.TableNode = uitreenode(app.Tree);
            app.TableNode.Text = 'Table';
            app.SPLTableNode = uitreenode(app.TableNode);
            app.SPLTableNode.Tag = 'table';
            app.SPLTableNode.Text = 'SPL';
            app.OctaveTableNode = uitreenode(app.TableNode);
            app.OctaveTableNode.Tag = 'table';
            app.OctaveTableNode.Text = 'Octave';
            app.PlotNode = uitreenode(app.Tree);
            app.PlotNode.Text = 'Plot';
            app.SPLNode = uitreenode(app.PlotNode);
            app.SPLNode.Tag = 'plot';
            app.SPLNode.Text = 'SPL';
            app.OctaveNode = uitreenode(app.PlotNode);
            app.OctaveNode.Text = 'Octave';
            app.barNode = uitreenode(app.OctaveNode);
            app.barNode.Tag = 'plot';
            app.barNode.Text = 'bar';
            app.lineNode = uitreenode(app.OctaveNode);
            app.lineNode.Tag = 'plot';
            app.lineNode.Text = 'line';
            app.tableResultTime = uitable(app.UIFigure);
            app.tableResultTime.ColumnName = {'Time'; 'dB'; 'dBA'; 'dBC'};
            app.tableResultTime.RowName = {};
            app.tableResultTime.Visible = 'off';
            app.TimeDropDownLabel = uilabel(app.UIFigure);
            app.TimeDropDownLabel.Text = 'Time';
            app.dropTime = uidropdown(app.UIFigure);
            app.dropTime.Items = {'Fast', 'Slow'};
            app.dropTime.Value = 'Fast';
            app.timePlot = uiaxes(app.UIFigure);
            xlabel(app.timePlot, 'Time')
            app.splPlot = uiaxes(app.UIFigure);
            title(app.splPlot, '1/3 ovtave')
            xlabel(app.splPlot, 'Freq')
            ylabel(app.splPlot, 'Level')
            zlabel(app.splPlot, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = splApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end